classdef cep < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ExitButton              matlab.ui.control.Button
        Phi_1Slider             matlab.ui.control.Slider
        Phi_1SliderLabel        matlab.ui.control.Label
        Theta_1Slider           matlab.ui.control.Slider
        Theta_1SliderLabel      matlab.ui.control.Label
        Omega_2Knob             matlab.ui.control.Knob
        Omega_2KnobLabel        matlab.ui.control.Label
        Theta_2Slider           matlab.ui.control.Slider
        Theta_2SliderLabel      matlab.ui.control.Label
        Phi_2Slider             matlab.ui.control.Slider
        Phi_2SliderLabel        matlab.ui.control.Label
        PolarPlotofSPanel       matlab.ui.container.Panel
        PolarPlotofE_2W_2Panel  matlab.ui.container.Panel
        PolarPlotofE_1W_1Panel  matlab.ui.container.Panel
        StartButton             matlab.ui.control.Button
        Omega_1Knob             matlab.ui.control.Knob
        Omega_1KnobLabel        matlab.ui.control.Label
        UIAxes6                 matlab.ui.control.UIAxes
        UIAxes5                 matlab.ui.control.UIAxes
        UIAxes4                 matlab.ui.control.UIAxes
        UIAxes3                 matlab.ui.control.UIAxes
        UIAxes2                 matlab.ui.control.UIAxes
        UIAxes                  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: Omega_1Knob, Omega_1Knob, Omega_2Knob, 
        % Omega_2Knob, Phi_1Slider, Phi_1Slider, Phi_2Slider, 
        % Phi_2Slider, StartButton, Theta_1Slider, Theta_1Slider, 
        % Theta_2Slider, Theta_2Slider
        function ButtonPushed(app, event)
            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes3);
            cla(app.UIAxes4);
            cla(app.UIAxes5);
            cla(app.UIAxes6);
            t = -pi:0.001:pi;

            %Omega_1 = 1;
            Omega_1 = app.Omega_1Knob.Value;
            Theta_1 = app.Theta_1Slider.Value;
            Phi_1 = app.Phi_1Slider.Value;
            %Theta_1 = log(0.5); Phi_1 = pi/2;
            %Omega_2 = 3; 
            %Theta_2 = log(0.5); 
            %Phi_2 = pi/2;
            Omega_2 = app.Omega_2Knob.Value;
            Theta_2 = app.Theta_2Slider.Value; 
            Phi_2 = app.Phi_2Slider.Value;
            
            
            E1 = exp((1i*Omega_1.*t)+Theta_1);
            W1 = exp(1i*Phi_1);
            
            E2 = exp((1i*Omega_2.*t)+Theta_2);
            W2 = exp(1i*Phi_2);
            
%             s1 = E1*W1;
%             s2 = E2*W2;
            s1 = E1;
            s2 = E2;
            S = s1 + s2;
            m1 = abs(s1);
            p1 = angle(s1);
            m2 = abs(s2);
            p2 = angle(s2);
            
            m = abs(S);
            p = angle(S);
            %Omega_1 = app.Omega_1Knob.Value;
            
            plot(app.UIAxes,t,real(s1));
            hold(app.UIAxes,'on');
            plot(app.UIAxes,t,imag(s1));
            
            
            plot(app.UIAxes2,t,real(s2));
            hold(app.UIAxes2,'on');
            plot(app.UIAxes2,t,imag(s2));
            
            plot(app.UIAxes3,t,real(S));
            hold(app.UIAxes3,'on');
            plot(app.UIAxes3,t,imag(S));
            
            %polarplot(app.UIAxes4,s1);
            pax = polaraxes(app.PolarPlotofE_1W_1Panel);
            polarplot(pax,s1);
            pax2 = polaraxes(app.PolarPlotofE_2W_2Panel);
            polarplot(pax2,s2);
            
            pax3 = polaraxes(app.PolarPlotofSPanel);
            pax3.ThetaDir = 'clockwise';
            %pax3.ThetaAxisUnits = 'radians';
            polarplot(pax3,S);
            
            plot(app.UIAxes4,t,m1);
            hold(app.UIAxes4,'on');
            plot(app.UIAxes4,t,p1);
            
            plot(app.UIAxes4,t,m1);
            hold(app.UIAxes4,'on');
            plot(app.UIAxes4,t,p1);
            
            plot(app.UIAxes5,t,m2);
            hold(app.UIAxes5,'on');
            plot(app.UIAxes5,t,p2);
            
            plot(app.UIAxes6,t,m);
            hold(app.UIAxes6,'on');
            plot(app.UIAxes6,t,p);
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'E_1W_1 = e^{i\omega_1t+\theta_1}e^{i\phi_1}')
            xlabel(app.UIAxes, 't')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'E_2W_2 = e^{i\omega_2t+\theta_2}e^{i\phi_2}')
            xlabel(app.UIAxes2, 't')
            ylabel(app.UIAxes2, 'Amplitude')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'S = E_1W_1+E_2W_2')
            xlabel(app.UIAxes3, 't')
            ylabel(app.UIAxes3, 'Amplitude')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Phase & Magnitude of E1W1')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Phase & Magnitude of E2W2')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes6 = uiaxes(app.UIFigure);
            title(app.UIAxes6, 'Phase & Magnitude of S')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.Omega_1KnobLabel = uilabel(app.UIFigure);
            app.Omega_1KnobLabel.Text = 'Omega_1';
            app.Omega_1Knob = uiknob(app.UIFigure, 'continuous');
            app.Omega_1Knob.Limits = [0 10];
            app.Omega_1Knob.ValueChangedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Omega_1Knob.ValueChangingFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Omega_1Knob.Value = 1;
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.PolarPlotofE_1W_1Panel = uipanel(app.UIFigure);
            app.PolarPlotofE_1W_1Panel.Title = 'Polar Plot of E_1W_1';
            app.PolarPlotofE_2W_2Panel = uipanel(app.UIFigure);
            app.PolarPlotofE_2W_2Panel.Title = 'Polar Plot of E_2W_2';
            app.PolarPlotofSPanel = uipanel(app.UIFigure);
            app.PolarPlotofSPanel.Title = 'Polar Plot of S';
            app.Phi_2SliderLabel = uilabel(app.UIFigure);
            app.Phi_2SliderLabel.Text = 'Phi_2';
            app.Phi_2Slider = uislider(app.UIFigure);
            app.Phi_2Slider.Limits = [-3.14 3.14];
            app.Phi_2Slider.ValueChangedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Phi_2Slider.ValueChangingFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Phi_2Slider.Value = 1.5707963267949;
            app.Theta_2SliderLabel = uilabel(app.UIFigure);
            app.Theta_2SliderLabel.Text = 'Theta_2';
            app.Theta_2Slider = uislider(app.UIFigure);
            app.Theta_2Slider.Limits = [-2.30258509299405 2.30258509299405];
            app.Theta_2Slider.ValueChangedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Theta_2Slider.ValueChangingFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Theta_2Slider.Value = -0.693147180559945;
            app.Omega_2KnobLabel = uilabel(app.UIFigure);
            app.Omega_2KnobLabel.Text = 'Omega_2';
            app.Omega_2Knob = uiknob(app.UIFigure, 'continuous');
            app.Omega_2Knob.Limits = [0 10];
            app.Omega_2Knob.ValueChangedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Omega_2Knob.ValueChangingFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Omega_2Knob.Value = 3;
            app.Theta_1SliderLabel = uilabel(app.UIFigure);
            app.Theta_1SliderLabel.Text = 'Theta_1';
            app.Theta_1Slider = uislider(app.UIFigure);
            app.Theta_1Slider.Limits = [-2.30258509299405 2.30258509299405];
            app.Theta_1Slider.ValueChangedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Theta_1Slider.ValueChangingFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Theta_1Slider.Value = -0.693147180559945;
            app.Phi_1SliderLabel = uilabel(app.UIFigure);
            app.Phi_1SliderLabel.Text = 'Phi_1';
            app.Phi_1Slider = uislider(app.UIFigure);
            app.Phi_1Slider.Limits = [-3.14 3.14];
            app.Phi_1Slider.ValueChangedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Phi_1Slider.ValueChangingFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Phi_1Slider.Value = 1.5707963267949;
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cep

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end